classdef cprapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ColorPerformanceReviewToolLabel  matlab.ui.control.Label
        OutputPanel                     matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        SampleNameEditField             matlab.ui.control.EditField
        SampleNameEditFieldLabel        matlab.ui.control.Label
        CreateOnePagerButton            matlab.ui.control.Button
        OnePager_FilenameEditField      matlab.ui.control.EditField
        OnePager_FilenameEditFieldLabel  matlab.ui.control.Label
        DeviceNameEditField             matlab.ui.control.EditField
        DeviceNameEditFieldLabel        matlab.ui.control.Label
        KnumberEditField                matlab.ui.control.EditField
        KnumberEditFieldLabel           matlab.ui.control.Label
        SponsorNameEditField            matlab.ui.control.EditField
        SponsorNameEditFieldLabel       matlab.ui.control.Label
        ConvertPanel                    matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        Image_4                         matlab.ui.control.Image
        Image_3                         matlab.ui.control.Image
        UITable_4                       matlab.ui.control.Table
        UITable_3                       matlab.ui.control.Table
        Image2                          matlab.ui.control.Image
        Image1                          matlab.ui.control.Image
        UITable_2                       matlab.ui.control.Table
        UITable_1                       matlab.ui.control.Table
        Panel1                          matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        ImportDataButton                matlab.ui.control.Button
        InputData_DeviceEditField       matlab.ui.control.EditField
        InputData_DeviceEditFieldLabel  matlab.ui.control.Label
        InputData_ReferenceEditField    matlab.ui.control.EditField
        InputData_ReferenceEditFieldLabel  matlab.ui.control.Label
        InputMethod_DeviceDropDown      matlab.ui.control.DropDown
        InputMethod_DeviceDropDownLabel  matlab.ui.control.Label
        ColorSpace_DeviceDropDown       matlab.ui.control.DropDown
        ColorSpace_DeviceDropDownLabel  matlab.ui.control.Label
        InputMethod_ReferenceDropDown   matlab.ui.control.DropDown
        InputMethod_ReferenceDropDownLabel  matlab.ui.control.Label
        ColorSpace_ReferenceDropDown    matlab.ui.control.DropDown
        ColorSpace_ReferenceDropDownLabel  matlab.ui.control.Label
        ColorTargetDropDown             matlab.ui.control.DropDown
        ColorTargetDropDownLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        LAB_Reference                                                       % final CIELAB for CPR, reference
        LAB_Device                                                          % final CIELAB for CPR, device output
        ct                                                                  % color target: ColorCheckerClass
        patch_n                                                             % number of patches: 24, 30, 48
        row_n_Reference                                                     % rows of data, reference (e.g., 24x3) 
        row_n_Device                                                        % rows of data, device out (e.g., 24x3) 
        app_path                                                            % pathname of the app
        t1                                                                  % table 1: input, reference
        t2                                                                  % table 2: input, device output
        t3                                                                  % table 3: CIELAB, reference
        t4                                                                  % table 4: CIELAB, device output
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.app_path = pwd;                                             % save the current dir
            % disp(app.app_path)

            app.ct = CPR.ColorCheckerClass;                                 % default color target 
            app.patch_n = 24;                                               % default rows of data (CIELAB or sRGB)    

            ColorSpace_ReferenceDropDownValueChanged(app);                  % refresh "ColorSpace_Reference"
            ColorSpace_DeviceDropDownValueChanged(app);                     % refresh "ColorSpace_Device" 
        end

        % Value changed function: ColorTargetDropDown
        function ColorTargetDropDownValueChanged(app, event)

            %
            % Decide the color target
            %
            switch app.ColorTargetDropDown.Value
                case 'ColorChecker'
                    app.ct = CPR.ColorCheckerClass;
                case 'ColorGauge'
                    app.ct = CPR.ColorGauge;
                case 'RezChecker'
                    app.ct = CPR.RezChecker;
                case 'SFRPlus'
                    app.ct = CPR.SFRPlus;
            end

            app.patch_n = app.ct.patch_n;                                   % set number of patches based on the color target

            ColorSpace_ReferenceDropDownValueChanged(app, event);           % refresh "ColorSpace_Reference"
            ColorSpace_DeviceDropDownValueChanged(app, event);              % refresh "ColorSpace_Device" 

            app.CreateOnePagerButton.Enable = "off";                        % need to check data before enabling             

        end

        % Value changed function: ColorSpace_ReferenceDropDown
        function ColorSpace_ReferenceDropDownValueChanged(app, event)
            
            switch app.ColorSpace_ReferenceDropDown.Value
                case 'XYZ'
                    app.row_n_Reference = app.patch_n + 1;                  % rows of data (e.g., 25x3) -- need reference white 
                case 'LAB'
                    app.row_n_Reference = app.patch_n;                      % rows of data (e.g., 24x3)    
                otherwise
                    app.row_n_Reference = app.patch_n;                      % rows of data (e.g., 24x3)  
            end

            value = app.ColorSpace_ReferenceDropDown.Value;
            
            switch app.InputMethod_ReferenceDropDown.Value
                case 'Filename'
                    app.InputData_ReferenceEditField.Value = ...
                        sprintf('%s%2dReference.txt', value, app.patch_n);  % suggest the filename (e.g., "LAB24Reference.txt") 
                otherwise
                    app.InputData_ReferenceEditField.Value = '';            % leave it empty for copy-n-paste 
            end

            app.CreateOnePagerButton.Enable = "off";                        % need to check data before enabling  
        end

        % Value changed function: ColorSpace_DeviceDropDown
        function ColorSpace_DeviceDropDownValueChanged(app, event)

            switch app.ColorSpace_DeviceDropDown.Value
                case 'XYZ'
                    app.row_n_Device = app.patch_n + 1;                     % rows of data (e.g., 25x3) -- need reference white 
                case 'LAB'
                    app.row_n_Device = app.patch_n;                         % rows of data (e.g., 24x3)   
                otherwise
                    app.row_n_Device = app.patch_n;                         % rows of data (e.g., 24x3) 
            end

            value = app.ColorSpace_DeviceDropDown.Value;
            
            switch app.InputMethod_DeviceDropDown.Value
                case 'Filename'
                    app.InputData_DeviceEditField.Value = ...
                        sprintf('%s%2dDevice.txt', value, app.patch_n);     % suggest the filename (e.g., "LAB24Device.txt")     
                otherwise
                    app.InputData_DeviceEditField.Value = '';               % leave it empty for copy-n-paste 
            end

            app.CreateOnePagerButton.Enable = "off";               
        end

        % Value changed function: InputMethod_ReferenceDropDown
        function InputMethod_ReferenceDropDownValueChanged(app, event)
            value = app.InputMethod_ReferenceDropDown.Value;

            switch app.InputMethod_ReferenceDropDown.Value
                case 'Filename'
                    app.InputData_ReferenceEditField.Value = ...
                        sprintf('%s%2dReference.txt', value, app.patch_n);  % suggest the filename (e.g., "LAB24Reference.txt") 
                otherwise
                    app.InputData_ReferenceEditField.Value = '';            % leave it empty for copy-n-paste 
            end

            app.CreateOnePagerButton.Enable = "off";                  
        end

        % Value changed function: InputMethod_DeviceDropDown
        function InputMethod_DeviceDropDownValueChanged(app, event)
            value = app.InputMethod_DeviceDropDown.Value;
            
            switch app.InputMethod_DeviceDropDown.Value
                case 'Filename'
                    app.InputData_DeviceEditField.Value = ...
                        sprintf('%s%2dDevice.txt', value, app.patch_n);     % suggest the filename (e.g., "LAB24Device.txt")  
                otherwise
                    app.InputData_DeviceEditField.Value = '';               % leave it empty for copy-n-paste
            end

            app.CreateOnePagerButton.Enable = "off";                        % need to check data before enabling             
        end

        % Value changed function: InputData_ReferenceEditField
        function InputData_ReferenceEditFieldValueChanged(app, event)

            app.CreateOnePagerButton.Enable = "off";                        % need to check data before enabling              
        end

        % Value changed function: InputData_DeviceEditField
        function InputData_DeviceEditFieldValueChanged(app, event)
            
            app.CreateOnePagerButton.Enable = "off";                        % need to check data before enabling  
        end

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(app, event)

            %
            % Get the input data
            %
            InputData_Reference = app.InputData_ReferenceEditField.Value;
            InputData_Device = app.InputData_DeviceEditField.Value;

            switch app.InputMethod_ReferenceDropDown.Value

                case 'CopyPaste'                                            % input data by copy-n-paste

                    Data_Reference = sscanf(InputData_Reference,'%f');      % read the data 
                    
                    if numel(Data_Reference) ~= app.row_n_Reference * 3     % check number of values
                        msg = sprintf('Reference data size error: expecting a %dx3 table',app.row_n_Reference);
                        uialert(app.UIFigure,msg,'Input data error');
                        disp(msg)
                        return
                    end                    

                    Data_Reference = ...
                        reshape(Data_Reference,3,app.row_n_Reference)';     % accept the input data

                case 'Filename'                                             % input data by filename

                    if ~isfile(InputData_Reference)                         % check the file
                        uialert(app.UIFigure,'Reference data file not found','Input data error');
                        disp('Reference data file not found')
                        return
                    end

                    Data_Reference = readmatrix(InputData_Reference);       % read the data file 
                    Data_Reference = Data_Reference(:,1:3);                 % crop the data

                    if size(Data_Reference,1) ~= app.row_n_Reference
                        msg = sprintf('Reference data size error: expecting a %dx3 table',app.row_n_Reference);
                        uialert(app.UIFigure,msg,'Input data error');
                        disp(msg)
                        return
                    end

                otherwise
                    disp 'Error'
                    return
            end

            switch app.InputMethod_DeviceDropDown.Value

                case 'CopyPaste'                                            % input data by copy-n-paste

                    Data_Device = sscanf(InputData_Device,'%f');            % read the data 

                    if numel(Data_Device) ~= app.row_n_Device * 3           % check number of values
                        msg = sprintf('Device data size error: expecting a %dx3 table',app.row_n_Device)
                        uialert(app.UIFigure,msg,'Input data error');
                        return
                    end                    
                    
                    Data_Device = ...
                        reshape(Data_Device,3,app.row_n_Device)';           % accept the input data

                case 'Filename'                                             % input data by filename
                    if ~isfile(InputData_Device)                            % check the file
                        uialert(app.UIFigure,'Device data file not found','Input data error');
                        return
                    end
                    
                    Data_Device = readmatrix(InputData_Device);             % read the data file 
                    Data_Device = Data_Device(:,1:3);                       % crop the data

                    if size(Data_Device,1) ~= app.row_n_Device
                        msg = sprintf('Device data size error: expecting a %dx3 table',app.row_n_Device)
                        uialert(app.UIFigure,msg,'Input data error');
                        return
                    end

                otherwise
                    disp 'Error'
                    return
            end


            %
            % Reproduce the input data -- the "Convert" section
            %
            tt1 = [Data_Reference];                                         % make an array #1 for input reference
            tt2 = [Data_Device];                                            % make an array #2 for input device output

            app.t1 = array2table(tt1);                                      % save table #1  
            app.t2 = array2table(tt2);                                      % save table #2  

            app.UITable_1.Data = app.t1;                                    % prepare the UI #1  
            app.UITable_2.Data = app.t2;                                    % prepare the UI #2  

            switch app.ColorSpace_ReferenceDropDown.Value                   % add the header  
                case 'RGB'
                    app.UITable_1.ColumnName = {'Ref_R','Ref_G','Ref_B'};
                case 'XYZ'
                    app.UITable_1.ColumnName = {'Ref_X','Ref_Y','Ref_Z'};
                case 'LAB'
                    app.UITable_1.ColumnName = {'Ref_L*','Ref_a*','Ref_b*'};
            end

            switch app.ColorSpace_DeviceDropDown.Value                      % add the header
                case 'RGB'
                    app.UITable_2.ColumnName = {'Dev_R','Dev_G','Dev_B'};
                case 'XYZ'
                    app.UITable_2.ColumnName = {'Dev_X','Dev_Y','Dev_Z'};
                case 'LAB'
                    app.UITable_2.ColumnName = {'Dev_L*','Dev_a*','Dev_b*'};
            end

            app.UITable_1.RowName = 'numbered';                             % add index as the first column
            app.UITable_2.RowName = 'numbered';                             % add index as the first column 

            %
            % Show the color target -- reference
            %
            switch app.ColorSpace_ReferenceDropDown.Value
                case 'RGB'
                    RGB_Reference = uint8(Data_Reference);
                    LAB_Reference = rgb2lab(RGB_Reference,'ColorSpace','srgb','WhitePoint','d65');

                case 'XYZ'
                    XYZ_d65 = rgb2xyz([1 1 1]);                             % get the white point XYZ
                    Y_d65 = XYZ_d65(2);                                     % get the white point Y   
                    Y_Reference = Data_Reference(end,2);                    % get Y of the reference white of the user's ground truth
                    XYZ_Reference = ...
                        Data_Reference(1:end-1,:) / Y_Reference * Y_d65;    % scale XYZ based on the reference white
                    
                    RGB_Reference = ...
                        xyz2rgb(XYZ_Reference,'ColorSpace','srgb','WhitePoint','d65')*255;

                    % check reference white
                    if Data_Reference(end,1) * Data_Reference(end,2) * Data_Reference(end,3) == 0                % if any of XYZ is zero
                        uialert(app.UIFigure,'Invalid reference white in reference data','Input data error');
                        return
                    end

                    % convert to LAB
                    LAB_Reference = xyz2lab(Data_Reference(1:end-1,:),'WhitePoint',Data_Reference(end,:));

                case 'LAB'
                    RGB_Reference = uint8(lab2rgb(Data_Reference,'ColorSpace','srgb','WhitePoint','d65')*255);
                    LAB_Reference = Data_Reference;
            end

            im1 = app.ct.getImage(RGB_Reference);                           % create a color target image
            app.Image1.ImageSource = im1;                                   % and show it

            %
            % Show the color target -- device output
            %
            switch app.ColorSpace_DeviceDropDown.Value
                case 'RGB'
                    RGB_Device = uint8(Data_Device);
                    LAB_Device = rgb2lab(RGB_Device,'ColorSpace','srgb','WhitePoint','d65');

                case 'XYZ'
                    XYZ_d65 = rgb2xyz([1 1 1]);                             % get the white point XYZ
                    Y_d65 = XYZ_d65(2);                                     % get the white point Y  
                    Y_Device = Data_Device(end,2);                          % get Y of the reference white of the user's ground truth
                    XYZ_Device = ...
                        Data_Device(1:end-1,:) / Y_Device * Y_d65;          % scale XYZ based on the reference white
                    
                    RGB_Device = ...
                        xyz2rgb(XYZ_Device,'ColorSpace','srgb','WhitePoint','d65')*255;

                    % check reference white
                    if Data_Device(end,1) * Data_Device(end,2) * Data_Device(end,3) == 0                        % if any of XYZ is zero
                        uialert(app.UIFigure,'Invalid reference white in device data','Input data error');
                        return
                    end
                    
                    % convert to LAB
                    LAB_Device = xyz2lab(Data_Device(1:end-1,:),'WhitePoint',Data_Device(end,:));

                case 'LAB'
                    RGB_Device = uint8(lab2rgb(Data_Device,'ColorSpace','srgb','WhitePoint','d65')*255);
                    LAB_Device = Data_Device;
            end

            im2 = app.ct.getImage(RGB_Device);                              % create a color target image
            app.Image2.ImageSource = im2;                                   % and show it

            %
            % Show the LAB tables
            %
            tt3 = [LAB_Reference];
            tt4 = [LAB_Device];

            app.t3 = array2table(tt3);
            app.t4 = array2table(tt4);

            app.UITable_3.Data = app.t3;
            app.UITable_3.ColumnName = {'Ref_L*','Ref_a*','Ref_b*'};
            app.UITable_3.RowName = 'numbered';

            app.UITable_4.Data = app.t4;
            app.UITable_4.ColumnName = {'Dev_L*','Dev_a*','Dev_b*'};
            app.UITable_4.RowName = 'numbered';

            %
            % Show the LAB images
            %
            im3 = app.ct.getImage(lab2rgb(LAB_Reference,'ColorSpace','srgb','WhitePoint','d65')*255);
            im4 = app.ct.getImage(lab2rgb(LAB_Device,'ColorSpace','srgb','WhitePoint','d65')*255);

            app.Image_3.ImageSource = im3;
            app.Image_4.ImageSource = im4;

            %
            % Assign the final CIELAB data for ColorPerformanceReview()
            %
            app.LAB_Reference = CPR.DeviceData;
            app.LAB_Reference.setLAB(LAB_Reference);
            
            app.LAB_Device = CPR.DeviceData;
            app.LAB_Device.setLAB(LAB_Device);

            app.CreateOnePagerButton.Enable = "on";
             
        end

        % Button pushed function: CreateOnePagerButton
        function CreateOnePagerButtonPushed(app, event)

            %% 
            % create the CPR object with the LAB datasets
            %
            cpr = CPR.ColorPerformanceReview(app.LAB_Reference, app.LAB_Device);

            %%
            % add the annotations for the one-page report
            %
            cpr.K_number = app.KnumberEditField.Value;
            cpr.MakeName = app.SponsorNameEditField.Value;
            cpr.DeviceName = app.DeviceNameEditField.Value;
            cpr.DUTName = app.SampleNameEditField.Value;
            
            % Overwrite the input_colorspace property
            % with the format like "RGB/LAB"
            % for the one-line title of the one-pager
            cpr.input_colorspace = sprintf('%s/%s',...
                app.ColorSpace_ReferenceDropDown.Value,...
                app.ColorSpace_DeviceDropDown.Value);
            
            % datapath for...
            cpr.datapath = app.app_path;

            %%
            % create the one-pager with the filename
            %
            cpr.one_pager(app.OnePager_FilenameEditField.Value);       

            %%
            % save the user input data in "one_pager.mat"
            %
            input_ColorTarget = app.ColorTargetDropDown.Value;
            input_ColorSpace_Reference = app.ColorSpace_ReferenceDropDown.Value;
            input_ColorSpace_Device = app.ColorSpace_DeviceDropDown.Value;
            input_InputMethod_Reference = app.InputMethod_ReferenceDropDown.Value;
            input_InputMethod_Device = app.InputMethod_DeviceDropDown.Value;
            input_InputData_Reference = app.InputData_ReferenceEditField.Value;
            input_InputData_Device = app.InputData_DeviceEditField.Value;
            input_t1 = app.t1;
            input_t2 = app.t2;
            input_t3 = app.t3;
            input_t4 = app.t4;
            input_K_number = cpr.K_number;
            input_MakeName = cpr.MakeName;
            input_DeviceName = cpr.DeviceName;
            input_DUTName = cpr.DUTName;
            save('one_pager.mat','cpr','input_*')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel1 = uipanel(app.UIFigure);
            app.Panel1.Title = 'Input';
            app.GridLayout = uigridlayout(app.Panel1);
            app.ColorTargetDropDownLabel = uilabel(app.GridLayout);
            app.ColorTargetDropDownLabel.Text = 'ColorTarget';
            app.ColorTargetDropDown = uidropdown(app.GridLayout);
            app.ColorTargetDropDown.Items = {'ColorChecker', 'ColorGauge', 'RezChecker', 'SFRPlus'};
            app.ColorTargetDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorTargetDropDownValueChanged, true);
            app.ColorTargetDropDown.Tooltip = {'Choose the color target'};
            app.ColorTargetDropDown.Value = 'ColorChecker';
            app.ColorSpace_ReferenceDropDownLabel = uilabel(app.GridLayout);
            app.ColorSpace_ReferenceDropDownLabel.Text = 'ColorSpace_Reference';
            app.ColorSpace_ReferenceDropDown = uidropdown(app.GridLayout);
            app.ColorSpace_ReferenceDropDown.Items = {'RGB', 'XYZ', 'LAB'};
            app.ColorSpace_ReferenceDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorSpace_ReferenceDropDownValueChanged, true);
            app.ColorSpace_ReferenceDropDown.Tooltip = {'Choose the color space for the ground truth'};
            app.ColorSpace_ReferenceDropDown.Value = 'LAB';
            app.InputMethod_ReferenceDropDownLabel = uilabel(app.GridLayout);
            app.InputMethod_ReferenceDropDownLabel.Text = 'InputMethod_Reference';
            app.InputMethod_ReferenceDropDown = uidropdown(app.GridLayout);
            app.InputMethod_ReferenceDropDown.Items = {'Filename', 'CopyPaste'};
            app.InputMethod_ReferenceDropDown.ValueChangedFcn = createCallbackFcn(app, @InputMethod_ReferenceDropDownValueChanged, true);
            app.InputMethod_ReferenceDropDown.Tooltip = {'Provide a data file or type in the data'};
            app.InputMethod_ReferenceDropDown.Value = 'Filename';
            app.ColorSpace_DeviceDropDownLabel = uilabel(app.GridLayout);
            app.ColorSpace_DeviceDropDownLabel.Text = 'ColorSpace_Device';
            app.ColorSpace_DeviceDropDown = uidropdown(app.GridLayout);
            app.ColorSpace_DeviceDropDown.Items = {'RGB', 'XYZ', 'LAB'};
            app.ColorSpace_DeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorSpace_DeviceDropDownValueChanged, true);
            app.ColorSpace_DeviceDropDown.Tooltip = {'Choose the color space for the device'};
            app.ColorSpace_DeviceDropDown.Value = 'LAB';
            app.InputMethod_DeviceDropDownLabel = uilabel(app.GridLayout);
            app.InputMethod_DeviceDropDownLabel.Text = 'InputMethod_Device';
            app.InputMethod_DeviceDropDown = uidropdown(app.GridLayout);
            app.InputMethod_DeviceDropDown.Items = {'Filename', 'CopyPaste'};
            app.InputMethod_DeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @InputMethod_DeviceDropDownValueChanged, true);
            app.InputMethod_DeviceDropDown.Tooltip = {'Provide a data file or type in the data'};
            app.InputMethod_DeviceDropDown.Value = 'Filename';
            app.InputData_ReferenceEditFieldLabel = uilabel(app.GridLayout);
            app.InputData_ReferenceEditFieldLabel.Text = 'InputData_Reference';
            app.InputData_ReferenceEditField = uieditfield(app.GridLayout, 'text');
            app.InputData_ReferenceEditField.ValueChangedFcn = createCallbackFcn(app, @InputData_ReferenceEditFieldValueChanged, true);
            app.InputData_ReferenceEditField.Tooltip = {'Enter the data filename or copy-n-paste the data here'};
            app.InputData_ReferenceEditField.Value = 'LAB_Reference.txt';
            app.InputData_DeviceEditFieldLabel = uilabel(app.GridLayout);
            app.InputData_DeviceEditFieldLabel.Text = 'InputData_Device';
            app.InputData_DeviceEditField = uieditfield(app.GridLayout, 'text');
            app.InputData_DeviceEditField.ValueChangedFcn = createCallbackFcn(app, @InputData_DeviceEditFieldValueChanged, true);
            app.InputData_DeviceEditField.Tooltip = {'Enter the data filename or copy-n-paste the data here'};
            app.InputData_DeviceEditField.Value = 'LAB_Device.txt';
            app.ImportDataButton = uibutton(app.GridLayout, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Tooltip = {'Import the data to generate CIELAB and images'};
            app.ImportDataButton.Text = 'ImportData';
            app.ConvertPanel = uipanel(app.UIFigure);
            app.ConvertPanel.Title = 'Convert';
            app.GridLayout2 = uigridlayout(app.ConvertPanel);
            app.UITable_1 = uitable(app.GridLayout2);
            app.UITable_1.ColumnName = '';
            app.UITable_1.ColumnEditable = true;
            app.UITable_1.Tooltip = {'Imported data for the reference'};
            app.UITable_1.Enable = 'off';
            app.UITable_2 = uitable(app.GridLayout2);
            app.UITable_2.ColumnName = '';
            app.UITable_2.ColumnEditable = true;
            app.UITable_2.Tooltip = {'Imported data for the device'};
            app.UITable_2.Enable = 'off';
            app.Image1 = uiimage(app.GridLayout2);
            app.Image1.Tooltip = {'Reproduced image based on the input data'};
            app.Image2 = uiimage(app.GridLayout2);
            app.Image2.Tooltip = {'Reproduced image based on the input data'};
            app.UITable_3 = uitable(app.GridLayout2);
            app.UITable_3.ColumnName = '';
            app.UITable_3.ColumnEditable = true;
            app.UITable_3.Tooltip = {'Converted CIELAB data for the reference'};
            app.UITable_3.Enable = 'off';
            app.UITable_4 = uitable(app.GridLayout2);
            app.UITable_4.ColumnName = '';
            app.UITable_4.ColumnEditable = true;
            app.UITable_4.Tooltip = {'Converted CIELAB data for the device'};
            app.UITable_4.Enable = 'off';
            app.Image_3 = uiimage(app.GridLayout2);
            app.Image_3.Tooltip = {'Reproduced image based on the CIELAB data'};
            app.Image_4 = uiimage(app.GridLayout2);
            app.Image_4.Tooltip = {'Reproduced image based on the CIELAB data'};
            app.OutputPanel = uipanel(app.UIFigure);
            app.OutputPanel.Title = 'Output';
            app.GridLayout3 = uigridlayout(app.OutputPanel);
            app.SponsorNameEditFieldLabel = uilabel(app.GridLayout3);
            app.SponsorNameEditFieldLabel.Text = 'SponsorName';
            app.SponsorNameEditField = uieditfield(app.GridLayout3, 'text');
            app.SponsorNameEditField.Tooltip = {'Information to be included in the one-pager'};
            app.SponsorNameEditField.Value = 'Company';
            app.KnumberEditFieldLabel = uilabel(app.GridLayout3);
            app.KnumberEditFieldLabel.Text = 'Knumber';
            app.KnumberEditField = uieditfield(app.GridLayout3, 'text');
            app.KnumberEditField.Tooltip = {'Information to be included in the one-pager'};
            app.KnumberEditField.Value = 'K000000';
            app.DeviceNameEditFieldLabel = uilabel(app.GridLayout3);
            app.DeviceNameEditFieldLabel.Text = 'DeviceName';
            app.DeviceNameEditField = uieditfield(app.GridLayout3, 'text');
            app.DeviceNameEditField.Tooltip = {'Information to be included in the one-pager'};
            app.DeviceNameEditField.Value = 'Product';
            app.OnePager_FilenameEditFieldLabel = uilabel(app.GridLayout3);
            app.OnePager_FilenameEditFieldLabel.Text = 'OnePager_Filename';
            app.OnePager_FilenameEditField = uieditfield(app.GridLayout3, 'text');
            app.OnePager_FilenameEditField.Tooltip = {'Enter the output filename for the one-pager'};
            app.OnePager_FilenameEditField.Value = 'one_pager.png';
            app.CreateOnePagerButton = uibutton(app.GridLayout3, 'push');
            app.CreateOnePagerButton.ButtonPushedFcn = createCallbackFcn(app, @CreateOnePagerButtonPushed, true);
            app.CreateOnePagerButton.Enable = 'off';
            app.CreateOnePagerButton.Tooltip = {'Generate the one-pager'};
            app.CreateOnePagerButton.Text = 'CreateOnePager';
            app.SampleNameEditFieldLabel = uilabel(app.GridLayout3);
            app.SampleNameEditFieldLabel.Text = 'SampleName';
            app.SampleNameEditField = uieditfield(app.GridLayout3, 'text');
            app.SampleNameEditField.Tooltip = {'Information to be included in the one-pager'};
            app.SampleNameEditField.Value = 'Sample';
            app.ColorPerformanceReviewToolLabel = uilabel(app.UIFigure);
            app.ColorPerformanceReviewToolLabel.Text = 'Color Performance Review Tool';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cprapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end